classdef InteractiveMysteryCurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        zEditField       matlab.ui.control.NumericEditField
        zEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.NumericEditField
        xEditFieldLabel  matlab.ui.control.Label
        cEditField       matlab.ui.control.NumericEditField
        cEditFieldLabel  matlab.ui.control.Label
        EquationLabel    matlab.ui.control.Label
        bEditField       matlab.ui.control.NumericEditField
        bEditFieldLabel  matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        aEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ax % imag axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define theta. Get a and b
            t = linspace(0,2*pi, 1000);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
             
            f = a*exp(x*1i*t) + b*exp(y*1i*t)  + c*exp(-z*1i*t);
            plotline = plot(app.ax, real(f), imag(f));
            
            % Set line color
            plotline.Color = [0,0,1];


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create polar axes and position it in pixels
            app.ax = axes(app.UIFigure);
            app.ax.Units = 'pixels';
            app.ax.Position = [260 55 230 230];
            
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: aEditField, bEditField
        function ComponentValueChanged(app, event)
            % Plot the  function
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Mystery Curve';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Limits = [2.22044604925031e-16 Inf];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.bEditField.Value = 1;
            app.EquationLabel = uilabel(app.UIFigure);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '$$f(t) = ae^{xit} + be^{yit} + ce^{-zit}$$';
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Value = 1;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Value = 6;
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.Value = 14;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteractiveMysteryCurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end