classdef InteractivePolarPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FunctionDropDown       matlab.ui.control.DropDown
        FunctionDropDownLabel  matlab.ui.control.Label
        EquationLabel          matlab.ui.control.Label
        bEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define theta. Get a and b
            theta = 0:pi/1800:2*pi;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
              
            % Calculate r and plot it
            if app.FunctionDropDown.Value == "Rose 1"
                r = a*sin(b*theta);
                app.EquationLabel.Text = '$$r = a\sin b\theta$$';
            elseif app.FunctionDropDown.Value == "Rose 2"
                r = a*cos(b*theta);
                app.EquationLabel.Text = '$$r = a\cos b\theta$$';
            elseif app.FunctionDropDown.Value == "Rose Pi"
                r = a*sin(pi*b*theta);
                app.EquationLabel.Text = '$$r = a\sin b\pi\theta$$';
            elseif app.FunctionDropDown.Value == "Limacon"
                r = a*sin(theta) + b;
                app.EquationLabel.Text = '$$r = a\sin\theta +  b$$';
            elseif app.FunctionDropDown.Value == "Limacon 2"
                r = a*cos(theta) + b;
                app.EquationLabel.Text = '$$r = a\cos\theta +  b$$';
            elseif app.FunctionDropDown.Value == "Lemniscates"
                r = sqrt(a*cos(b*theta - pi/2));
                app.EquationLabel.Text = '$$r = \sqrt{a\cos b\theta -  \pi/2}$$';
            end

            plotline = polarplot(app.Pax, theta, r);
            
            % Set line color
            plotline.Color = [0,0,1];


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
            
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: FunctionDropDown, aEditField, bEditField
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.aEditField.Value = 5;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Limits = [2.22044604925031e-16 Inf];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.bEditField.Value = 2;
            app.EquationLabel = uilabel(app.UIFigure);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '$$r = a\sin b\theta$$';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'Rose 1', 'Rose 2', 'Rose Pi', 'Limacon', 'Lemniscates'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.FunctionDropDown.Value = 'Rose 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteractivePolarPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end